classdef matlabCA_Ka_v9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeasureDropletsUIFigure  matlab.ui.Figure
        DropletTypeSwitch        matlab.ui.control.Switch
        DropletTypeSwitchLabel   matlab.ui.control.Label
        CopyResultsButton        matlab.ui.control.Button
        ExportDataButton         matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        Button                   matlab.ui.control.Button
        RotateButton             matlab.ui.control.Button
        ZoomButton               matlab.ui.control.Button
        MeasureDropletButton     matlab.ui.control.Button
        ncontEditField           matlab.ui.control.NumericEditField
        ncontEditFieldLabel      matlab.ui.control.Label
        nFCEditField             matlab.ui.control.NumericEditField
        nFCEditFieldLabel        matlab.ui.control.Label
        nHCEditField             matlab.ui.control.NumericEditField
        nHCEditFieldLabel        matlab.ui.control.Label
        OpenImageButton          matlab.ui.control.Button
    end

properties (Access = private)
            ri_hc = 1.33;
            ri_fc = 1.33;
            ri_cont = 1.33;
            data = [];
            extendedData = [];
            fullData = [];
            gcf = [];
            image = 'example.jpg';
            color = [0.48 0.01 0.14];
            f = [];
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath pwd
            app.gcf = imshow(app.image);
        end

        % Value changed function: nHCEditField
        function nHCEditFieldValueChanged(app, event)
            app.ri_hc = app.nHCEditField.Value;
            
        end

        % Value changed function: nFCEditField
        function nFCEditFieldValueChanged(app, event)
            app.ri_fc = app.nFCEditField.Value;
            
        end

        % Value changed function: ncontEditField
        function ncontEditFieldValueChanged(app, event)
            app.ri_cont = app.ncontEditField.Value;
        end

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)

            app.f = uigetfile(pwd, 'Select your image','*');
            app.image = imread(app.f);
            [m n] = size(app.image);
            axis([0 m 0 n]);
            app.gcf = imshow(app.image);
            app.data = [];
            app.fullData = [];
            app.UITable.Data = app.data;
            hold on; 
        end

        % Button pushed function: MeasureDropletButton
        function MeasureDropletButtonPushed(app, event)

            switch app.DropletTypeSwitch.Value
                case 'Spherical'
                    title('Click 6 points, in order, 3 for the outer circle, 3 for the inner')
                    coords = round(ginput_custom(6));
                    drop_outer = [coords(1:3,1);coords(1:3,2)]; % 3 points for inner circle, 3 points for outer circle
                    ri_c = app.ri_cont;
                    ri_f = app.ri_fc;
                    ri_h = app.ri_hc;
                    
                    drop{1} = [coords(1:3,1),coords(1:3,2)];
                    drop{2} = [coords(4:6,1),coords(4:6,2)];
                    
                    for i = 1:2
                        drop_coords = [drop{i}];
                        x = drop_coords(:,1);
                        y = drop_coords(:,2);
                        leftOrthog = (y(2)-y(1))./(x(2)-x(1));
                        rightOrthog = (y(3)-y(2))./(x(3)-x(2));
                        interfaceFit = (leftOrthog.*rightOrthog.*(y(3)-y(1))+leftOrthog.*(x(2)+x(3))-rightOrthog.*(x(1)+x(2)))./(2*(leftOrthog-rightOrthog));
                        interfaceFit(:,2) = -1./leftOrthog.*(interfaceFit-(x(1)+x(2))/2)+((y(1)+y(2))/2);
                        interfaceRadii = sqrt((interfaceFit(:,1)-x(1)).^2+(interfaceFit(:,2)-y(1)).^2);
                        if isempty(interfaceFit) == 0
                            curveCenters(i,:) = interfaceFit(1,:);
                            curveRadii(i) = interfaceRadii(1,:);
                        end
                    end
                    loc_outer = curveCenters(1,:);
                    radii_outer = curveRadii(1);
                    loc_inner = curveCenters(2,:);
                    radii_inner = curveRadii(2);
                    d = norm(loc_outer - loc_inner);
                    
                    % Here is the switch for spherical or non spherical, everything
                    % before is still two circles
                    
                    if loc_inner(2) > loc_outer(2)
                        cor = ri_c/ri_h;
                        contact_angle = 180-acos((((radii_inner*cor)^2)+(radii_outer^2)-((d*cor)^2))/(2*radii_inner*radii_outer))*180/pi;
                    else
                        cor = ri_c/ri_f;
                        contact_angle = acos((((radii_inner*cor)^2)+(radii_outer^2)-((d*cor)^2))/(2*radii_inner*radii_outer))*180/pi;
                    end
                    
                    contact_angle_fc = 180 - contact_angle; 
                    
                    d_trip_outer = (d^2 - radii_inner^2 + radii_outer^2)/(2*d);
                    d_trip_inner = d - d_trip_outer;
                    
                    V_drop = (4/3)*pi*(radii_outer^3);
                    h_cap = radii_outer - d_trip_outer;
                    
                    loc_three = [loc_outer(1),loc_inner(2)];
                    tilt_angle = atan2d(abs(det([loc_three-loc_outer;loc_inner-loc_outer])),dot(loc_three-loc_outer,loc_inner-loc_outer));
                    
                    if d_trip_inner <= 0
                        h_caphcfc = radii_inner + d_trip_inner;
                        radii_cap = sqrt(radii_outer^2 - d_trip_outer^2);
                        V_inner = (4/3)*pi*(radii_inner^3);
                        V_caphcfc = V_inner - (pi*h_caphcfc*(3*(radii_cap^2)+(h_caphcfc^2)))/6;
                    else
                        radii_cap = sqrt(radii_outer^2 - d_trip_outer^2);
                        h_caphcfc = radii_inner - d_trip_inner;
                        V_caphcfc = (pi*h_caphcfc*(3*(radii_cap^2)+(h_caphcfc^2)))/6;
                    end
                    V_cap = (pi*h_cap*(3*(radii_cap^2)+(h_cap^2)))/6;
                    surface_drop = 4*pi*radii_outer^2;
                    if loc_inner(2) > loc_outer(2)
                        V_fc = V_cap + V_caphcfc;
                        V_hc = V_drop - V_fc;
                        surface_fc = 2*pi*radii_outer*h_cap;
                        surface_cap = surface_drop - surface_fc;
                    else
                        V_hc = V_cap + V_caphcfc;
                        V_fc = V_drop - V_hc;
                        surface_cap = 2*pi*radii_outer*h_cap;
                        surface_fc = surface_drop - surface_cap;
                    end
                    janus_ratio = surface_cap/surface_fc;
                    V_ratio = V_hc/V_fc;

                    hold on;
                    viscircles(loc_outer,radii_outer,'Color',app.color);
                    viscircles(loc_inner,radii_inner,'Color',app.color,'LineStyle','--');
                    title(['RI_h_c: ', num2str(ri_h), '// RI_f_c: ', num2str(ri_f), '// RI_c_o_n_t: ',num2str(ri_c)]);
                    
                    if loc_inner(2) > loc_outer(2)
                        text(loc_outer(1)+radii_cap+5,loc_outer(2)+d_trip_outer,['CA_h_c: ',num2str(contact_angle),'\circ'],'Color',app.color)
                        text(loc_outer(1)+radii_cap+50,loc_outer(2)+d_trip_outer,['CA_f_c: ',num2str(contact_angle_fc),'\circ'],'Color',app.color)
                    else
                        text(loc_outer(1)+radii_cap+5,loc_outer(2)-d_trip_outer,['CA_h_c: ',num2str(contact_angle),'\circ'],'Color',app.color)
                        text(loc_outer(1)+radii_cap+50,loc_outer(2)-d_trip_outer,['CA_f_c: ',num2str(contact_angle_fc),'\circ'],'Color',app.color)
                    end
                    
                    text(loc_inner(1),loc_inner(2),['V_h_c/V_f_c: ',num2str(V_ratio)],'Color',app.color)
                    text(loc_inner(1),loc_inner(2)+25,['R_d_r_o_p: ',num2str(radii_outer)],'Color',app.color)
                    text(loc_inner(1),loc_inner(2)+50,['R_i_n_n_e_r: ',num2str(radii_inner)],'Color',app.color)
                    text(loc_inner(1),loc_inner(2)+75,['D: ',num2str(d)],'Color',app.color)
                    %text(loc_inner(1),loc_inner(2)+120,['d_t_r_i_p: ',num2str(d_trip_outer)],'Color',app.color)
                    text(loc_inner(1),loc_inner(2)+100,['Janus Ratio: ',num2str(janus_ratio)],'Color',app.color)
                    text(loc_inner(1),loc_inner(2)+120,['Tilt Angle: ',num2str(tilt_angle)],'Color',app.color)
                    hold off;
                    app.fullData = vertcat(app.fullData,[radii_inner,radii_outer,d,contact_angle,contact_angle_fc,V_ratio,janus_ratio]);
                    CA_err = std(app.fullData(:,4));
                    V_ratio_err = std(app.fullData(:,5));
                    janus_ratio_err = std(app.fullData(:,6));
                    app.data = [mean(app.fullData(:,4)),CA_err,mean(app.fullData(:,5)),V_ratio_err,mean(app.fullData(:,6)),janus_ratio_err];
                    app.UITable.Data = app.data;
                    
                %Here is the switch for spherical or non spherical, everything
                %before is still two circles    
                case 'Snowman'
                    title('Click 5 points, in order, 2 for intersections, 1 for droplet phase 1, 1 for droplet phase 2, 1 for inner')
                    coords = round(ginput_custom(5));
                    ri_c = app.ri_cont;
                    ri_f = app.ri_fc;
                    ri_h = app.ri_hc;
                    
                    %drop{1} = [coords(1:3,1),coords(1:3,2)];
                    %drop{2} = [coords(4:6,1),coords(4:6,2)];
                    %drop{3} = [coords(7,1),coords(7,2)];
                    
                    drop{1} = [coords(3,1),coords(3,2)];
                    drop{2} = [coords(4,1),coords(4,2)];
                    drop{3} = [coords(5,1),coords(5,2)];
                    
                    int_1 = [coords(1,1),coords(1,2)];
                    int_2 = [coords(2,1),coords(2,2)];
                    
                    for i = 1:2
                        drop_coords = [int_1; drop{i};int_2];
                        x = drop_coords(:,1);
                        y = drop_coords(:,2);
                        leftOrthog = (y(2)-y(1))./(x(2)-x(1));
                        rightOrthog = (y(3)-y(2))./(x(3)-x(2));
                        interfaceFit = (leftOrthog.*rightOrthog.*(y(3)-y(1))+leftOrthog.*(x(2)+x(3))-rightOrthog.*(x(1)+x(2)))./(2*(leftOrthog-rightOrthog));
                        interfaceFit(:,2) = -1./leftOrthog.*(interfaceFit-(x(1)+x(2))/2)+((y(1)+y(2))/2);
                        interfaceRadii = sqrt((interfaceFit(:,1)-x(1)).^2+(interfaceFit(:,2)-y(1)).^2);
                        if isempty(interfaceFit) == 0
                            curveCenters(i,:) = interfaceFit(1,:);
                            curveRadii(i) = interfaceRadii(1,:);
                        end
                    end
                    
                    loc_hc = curveCenters(1,:);
                    radii_hc = curveRadii(1);
                    loc_fc = curveCenters(2,:);
                    radii_fc = curveRadii(2);
                    d = norm(loc_fc - loc_hc); % d means distance between hc and fc spheres
                    
%                     % Add correction factor of refractive index
%                     cor1 = ri_c/ri_h;
%                         contact_angle = 180-acos((((radii_inner*cor1)^2)+(radii_outer^2)-((d*cor1)^2))/(2*radii_inner*radii_outer))*180/pi;
%                     cor2 = ri_c/ri_f;
%                         contact_angle = acos((((radii_inner*cor2)^2)+(radii_outer^2)-((d*cor2)^2))/(2*radii_inner*radii_outer))*180/pi;
%                     

                    %Intersection of the two circles
                    cosAlpha = (radii_fc^2+d^2-radii_hc^2)/(2*radii_fc*d);
                    u_AB = (loc_hc - loc_fc)/d; %# unit vector from first to second center
                    pu_AB = [u_AB(2), -u_AB(1)]; %# perpendicular vector to unit vector
%                     int_1 = loc_fc + u_AB * (radii_fc*cosAlpha) + pu_AB * (radii_fc*sqrt(1-cosAlpha^2));
%                     int_2 = loc_fc + u_AB * (radii_fc*cosAlpha) - pu_AB * (radii_fc*sqrt(1-cosAlpha^2));

                                        
                    %Now we use the same thing but we make a perpendicular
                    %transformation between the circles and one point
                    u_hc = (loc_hc - int_1)/radii_hc;
                    pu_hc = [u_hc(2), -u_hc(1)];
                    u_fc = (loc_fc - int_1)/radii_fc;
                    pu_fc = [u_fc(2), -u_fc(1)];
                    %here are two perpindiular vectors to the intersection
                    %points of the circles, now angles between
                    CosTheta = max(min(dot(pu_hc,pu_fc)/(norm(pu_hc)*norm(pu_fc)),1),-1);
                    ThetaInDegrees = 180 - real(acosd(CosTheta));
                    CA_snowman = ThetaInDegrees;

                    
                    %Here we use the seventh,eighth and nineth point and the intersection of
                    %the two circles to calcualte the internal contact
                    %angle
                    
                    %drop_coords = [drop{3};int_1;int_2];
                    drop_coords_internal = [int_1; drop{3};int_2];
                    x_internal = drop_coords_internal(:,1);
                    y_internal = drop_coords_internal(:,2);
                    leftOrthog = (y_internal(2)-y_internal(1))./(x_internal(2)-x_internal(1));
                    rightOrthog = (y_internal(3)-y_internal(2))./(x_internal(3)-x_internal(2));
                    interfaceFit = (leftOrthog.*rightOrthog.*(y_internal(3)-y_internal(1))+leftOrthog.*(x_internal(2)+x_internal(3))-rightOrthog.*(x_internal(1)+x_internal(2)))./(2*(leftOrthog-rightOrthog));
                    interfaceFit(:,2) = -1./leftOrthog.*(interfaceFit-(x_internal(1)+x_internal(2))/2)+((y_internal(1)+y_internal(2))/2);
                    interfaceRadii = sqrt((interfaceFit(:,1)-x_internal(1)).^2+(interfaceFit(:,2)-y_internal(1)).^2);
                    if isempty(interfaceFit) == 0
                        curveCenters(3,:) = interfaceFit(1,:);
                        curveRadii(3) = interfaceRadii(1,:);
                    end
                    
                    loc_internal = curveCenters(3,:);
                    radii_internal = curveRadii(3);
        
                    u_internal = (loc_internal - int_1)/radii_internal;
                    pu_internal = [u_internal(2), -u_internal(1)];
                    u_fc_internal = (loc_fc - int_1)/radii_fc;
                    pu_fc_internal = [u_fc_internal(2), -u_fc_internal(1)];                    

                    CosTheta_fc = max(min(dot(pu_internal,pu_fc_internal)/(norm(pu_internal)*norm(pu_fc_internal)),1),-1);
                    ThetaInDegrees_fc = 180 - real(acosd(CosTheta_fc));
                   
                    
                    % now calculate surface area ratio and volume ratio
                    %d_trip_fc = (d^2 - radii_hc^2 + radii_fc^2)/(2*d); % distance of fc to the line to two intersections
                    %d_trip_hc = d - d_trip_fc; % put penalty on d_trip_hc, can be > 0 or < 0
                    d_trip_fc = (d^2 - radii_hc^2 + radii_fc^2)/(2*d); % distance of fc to the line to two intersections
                    d_trip_hc = d - d_trip_fc; % put penalty on d_trip_hc, can be > 0 or < 0

                   
                    % Vector representation of the line int_1 -> int_2
                    %line_vector = [x2 - x1, y2 - y1];
                    line_vector = int_2 - int_1;

                    % Vector from int_1 to the center of circle hc
                    %hc_vector = [x_hc - x1, y_hc - y1];
                    hc_vector = loc_hc - int_1;

                    % Vector from int_1 to the center of circle fc
                    %fc_vector = [x_fc - x1, y_fc - y1];
                    fc_vector = loc_fc - int_1;
                    
                    % Vector from int_1 to the center of circle internal
                    %internal_vector = [x_internal - x1, y_internal - y1];
                    internal_vector = loc_internal - int_1;

                    % Calculate the cross product of the line vector and the vectors to the centers
                    cross_hc = line_vector(1) * hc_vector(2) - line_vector(2) * hc_vector(1);
                    cross_fc = line_vector(1) * fc_vector(2) - line_vector(2) * fc_vector(1);
                    cross_internal = line_vector(1) * internal_vector(2) - line_vector(2) * internal_vector(1);
                    
                    cross_hc = -cross_hc; % i dont know why, but giving this negative sign and making it possive is the right sign that i want
                    cross_fc = -cross_fc;
                    cross_internal = -cross_internal;


                    % Penalty of position of FC shpere and internal
                    % interface sphere, which one has higher y-axis of
                    % centre point coordinates
                    %Snowman_penalty = (radii_fc + radii_hc)/8;
                    distance_hcfc_centre = sqrt((loc_hc(1) - loc_fc(1))^2 + (loc_hc(2) - loc_fc(2))^2); %calculate distance between HC and FC centres
                    %abs_d = abs(d);
                    diameter_hc = radii_hc*2;
                    diameter_fc = radii_fc*2;
                    Snowman_penalty = radii_fc + radii_hc + distance_hcfc_centre;
                    distance_intersections = sqrt((int_2(1) - int_1(1))^2 + (int_2(2) - int_1(2))^2); %calculate distance between two intersections
                    larger_radius = max(max(diameter_fc, diameter_hc), distance_intersections);
                    Aspect_ratio = Snowman_penalty/larger_radius;
                    %Aspect_ratio = larger_radius/Snowman_penalty;
                    
                    %
                    %                     % if abs_d > Snowman_penalty % means droplets more snowman
                    %                     if Aspect_ratio > 1.2
                    %                         %if cross_hc > 0
                    %                             if cross_fc < 0
                    %                                 if loc_fc > loc_internal
                    %                                     ThetaInDegrees_fc_penalty = ThetaInDegrees_fc;
                    %                                 else
                    %                                     ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
                    %                                 end
                    %                             else % cross_fc > 0
                    %                                 %if loc_fc > loc_internal
                    %                                 %    ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc; %correct
                    %                                 %else
                    %                                 %    ThetaInDegrees_fc_penalty = ThetaInDegrees_fc; %correct
                    %                                 %end
                    %                                 ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
                    %                             end
                    % %                         else % cross_hc < 0
                    % %                             if cross_fc < 0
                    % %                                 if loc_fc > loc_internal
                    % %                                     ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
                    % %                                 else
                    % %                                     ThetaInDegrees_fc_penalty = ThetaInDegrees_fc;
                    % %                                 end
                    % %                             else % cross_fc > 0
                    % %                                 if loc_fc > loc_internal
                    % %                                     ThetaInDegrees_fc_penalty = ThetaInDegrees_fc;
                    % %                                 else
                    % %                                     ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
                    % %                                 end
                    % %                                 %ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
                    % %                             end
                    % %                         end
                    %
                    %                     else % means droplets more spherical
                    %                         if cross_fc > 0
                    %                             if loc_fc > loc_internal
                    %                                 ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
                    %                             else
                    %                                 ThetaInDegrees_fc_penalty = ThetaInDegrees_fc;
                    %                             end
                    %                         else % cross_fc < 0
                    %                             if loc_fc > loc_internal
                    %                                 if cross_hc > 0
                    %                                     ThetaInDegrees_fc_penalty = ThetaInDegrees_fc;
                    %                                 else
                    %                                     ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
                    %                                 end
                    %                             else %loc_fc < loc_internal
                    %                                 if cross_hc > 0
                    %                                     ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
                    %                                 else
                    %                                     ThetaInDegrees_fc_penalty = ThetaInDegrees_fc;
                    %                                 end
                    %                             end
                    %                         end
                    %                     end


                    if Aspect_ratio > 1.1 % means droplets more snowman
%                         if cross_fc > 0
%                             if loc_fc > loc_internal
%                                 ThetaInDegrees_fc_penalty = ThetaInDegrees_fc;
%                             else
%                                 ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
%                             end
%                         else %cross_fc < 0
%                             %ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
%                             if loc_fc > loc_internal
%                                 ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
%                             else
%                                 ThetaInDegrees_fc_penalty = ThetaInDegrees_fc;
%                             end
%                         end

                        % or set the rule by myself :)
%                         if loc_fc > loc_internal
%                             ThetaInDegrees_fc_penalty = ThetaInDegrees_fc;
%                         else
%                             ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
%                         end
                         ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc; % change here for tuning which angle to take, if it is < 90, use 180-ThetaInDegrees_fc; if it is > 90, use ThetaInDegrees_fc
                        
                    else % means droplets more spherical
                        if cross_fc > 0
                            if loc_fc > loc_internal
                                ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
                            else
                                ThetaInDegrees_fc_penalty = ThetaInDegrees_fc;
                            end
                        else % cross_fc < 0
                            if loc_fc < loc_internal
                                if cross_hc > 0
                                    ThetaInDegrees_fc_penalty = ThetaInDegrees_fc;
                                else
                                    ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
                                end
                            else %loc_fc > loc_internal
                                if cross_hc > 0
                                    ThetaInDegrees_fc_penalty = ThetaInDegrees_fc;
                                else
                                    ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
                                end
                            end

                            % or just make the rules by myself to save time
%                             if cross_hc > 0
%                                 if loc_fc < loc_internal
%                                     ThetaInDegrees_fc_penalty = ThetaInDegrees_fc; 
%                                 else
%                                     ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
%                                 end
%                             else %cross_hc < 0
%                                 if loc_fc > loc_internal
%                                     ThetaInDegrees_fc_penalty = ThetaInDegrees_fc;
%                                 else
%                                     ThetaInDegrees_fc_penalty = 180-ThetaInDegrees_fc;
%                                 end
%                             end
                        end
                    end

                    
                    %Contact angle of HC    
                    CA_fc = ThetaInDegrees_fc_penalty;
                    CA_hc = 360-ThetaInDegrees-ThetaInDegrees_fc_penalty; 
                    
                    
                    % Calculate angles from the two intersction points and
                    % hc or fc sphere centre 
                    p1 = int_1; % Define points in coordinates
                    p2 = int_2;
                    p3 = loc_hc;
                    p4 = loc_fc;
                    p5 = loc_internal;
                   
                    % Calculate surface area of hc, fc respectively
                    % for hc
                    v1 = p1 - p3; % Calculate vectors from the points
                    v2 = p2 - p3;
                    cos_angle_hc = dot(v1, v2) / (norm(v1) * norm(v2));
                    %cos_angle_hc = max(min(cos_angle_hc, 1), -1);
                    angle_rad_hc = acos(cos_angle_hc); % Calculate the angle in radians
                    %angle_deg_hc = rad2deg(angle_rad_hc); % Convert the angle to degrees, but seems not necessary
                    surface_sphere_hc = 4*pi*radii_hc^2;
                    %h_hc = radii_hc*(1-cos(angle_deg_hc/2));
                    half_angle_rad_hc = angle_rad_hc/2;
                    h_hc = radii_hc*(1-cos(half_angle_rad_hc));
                    if cross_hc < 0 % Center of circle hc is below the line
                        surface_hc = 2*pi*radii_hc*h_hc;
                    elseif cross_hc > 0 % Center of circle hc is above the line
                        surface_hc = surface_sphere_hc - 2*pi*radii_hc*h_hc;
                    else % cross_hc == 0
                        surface_hc = 2*pi*radii_hc^2;
                    end

                    % for fc
                    v3 = p1 - p4;
                    v4 = p2 - p4;
                    cos_angle_fc = dot(v3, v4) / (norm(v3) * norm(v4));
                    %cos_angle_fc = max(min(cos_angle_fc, 1), -1); % this step helps to always measure the smaller angle, so smaller than 180 or pi
                    angle_rad_fc = acos(cos_angle_fc); % Calculate the angle in radians
                    surface_sphere_fc = 4*pi*radii_fc^2;
                    half_angle_rad_fc = angle_rad_fc/2;
                    h_fc = radii_fc*(1-cos(half_angle_rad_fc));
                    if cross_fc < 0 % Center of circle fc is below the line
                        surface_fc = surface_sphere_fc - 2*pi*radii_fc*h_fc;
                    elseif cross_fc > 0 % Center of circle fc is above the line
                        surface_fc = 2*pi*radii_fc*h_fc;
                    else % cross_fc == 0
                        surface_fc = 2*pi*radii_fc^2;
                    end
                    
                    % for internal sphere
                    v5 = p1 - p5;
                    v6 = p2 - p5;
                    cos_angle_internal = dot(v5, v6) / (norm(v5) * norm(v6));
                    %cos_angle_internal = max(min(cos_angle_internal, 1), -1); % this step helps to always measure the smaller angle, so smaller than 180 or pi
                    angle_rad_internal = acos(cos_angle_internal); % Calculate the angle in radians                 
                    half_angle_rad_internal = angle_rad_internal/2;
                    cos_half_angle_rad_internal = cos(half_angle_rad_internal);
                    h_internal = radii_internal*(1 - cos_half_angle_rad_internal);                    
                    
                                        
                    % need to calculate V_hc, V_fc, V_internal respectively                   
                    V_sphere_hc = (4/3)*pi*(radii_hc^3); % calculte the spherical volumes of HC and FC respectively
                    V_sphere_fc = (4/3)*pi*(radii_fc^3);
                    V_sphere_internal = (4/3)*pi*(radii_internal^3);
                    
                    V_hemisphere_hc = (2/3)*pi*(radii_hc^3);
                    V_hemisphere_fc = (2/3)*pi*(radii_fc^3);
                    V_hemisphere_internal = (2/3)*pi*(radii_internal^3);
                    
                    V_hc_cap = pi*h_hc^2*(3*radii_hc-h_hc)/3;
                    V_fc_cap = pi*h_fc^2*(3*radii_fc-h_fc)/3;
                    V_internal_cap = pi*h_internal^2*(3*radii_internal-h_internal)/3;
                    
                    V_hc_rest = V_sphere_hc - V_hc_cap;
                    V_fc_rest = V_sphere_fc - V_fc_cap;
                    V_internal_rest = V_sphere_internal - V_internal_cap;

                    
                    % for hc and fc combined
                    if cross_hc < 0 % Center of circle hc is below the line
                        if cross_fc < 0 % Center of circle hc is below the line
                            if cross_internal < 0 % Center of circle internal is below the line
                                V_hc = V_hc_cap + V_internal_rest;
                                V_fc = V_fc_rest - V_internal_rest;
                            elseif cross_internal > 0 % Center of circle internal is above the line
                                V_hc = V_hc_cap + V_internal_cap;
                                V_fc = V_fc_rest - V_internal_cap;
                            else % cross_internal == 0, Center of circle internal is on the line
                                V_hc = V_hc_cap + V_hemisphere_internal;
                                V_fc = V_fc_rest - V_hemisphere_internal;
                            end
                            %elseif cross_fc > 0 % Center of circle hc is above the line, the case where droplet is tilted or not gravity aligned
                            %    if cross_internal < 0 % Center of circle internal is below the line
                            %        V_hc = ;
                            %        V_fc = ;
                            %    elseif cross_internal > 0 % Center of circle internal is above the line
                            %        V_hc = ;
                            %        V_fc = ;
                            %    else % cross_internal == 0, Center of circle internal is on the line
                            %        V_hc = ;
                            %        V_fc = ;
                            %   end
                        else % cross_fc == 0
                            if cross_internal < 0 % Center of circle internal is below the line
                                V_hc = V_hc_cap - V_internal_cap;
                                V_fc = V_hemisphere_fc + V_internal_cap;
                            else % cross_internal > 0 % Center of circle internal is above the line
                                V_hc = V_hc_cap + V_internal_cap;
                                V_fc = V_hemisphere_fc - V_internal_cap;
                                %else % cross_internal == 0, Center of circle internal is on the line
                                %    V_hc = V_hc_cap + V_hemisphere_internal;
                                %    V_fc = V_hemisphere_fc - V_hemisphere_internal;
                            end
                        end
                    elseif cross_hc > 0 % Center of circle hc is above the line
                        if cross_fc < 0 % Center of circle hc is below the line
                            if cross_internal < 0 % Center of circle internal is below the line
                                V_hc = V_hc_rest - V_internal_cap;
                                V_fc = V_fc_rest + V_internal_cap;
                            else %if  cross_internal > 0 % Center of circle internal is above the line
                                V_hc = V_hc_rest + V_internal_cap;
                                V_fc = V_fc_rest - V_internal_cap;
                            %else % cross_internal == 0, Center of circle internal is on the line
                            %    V_hc = V_hc_rest + V_hemisphere_internal;
                            %    V_fc = V_fc_rest - V_hemisphere_internal;
                            end
                        elseif cross_fc > 0 % Center of circle hc is above the line, the case where droplet is tilted or not gravity aligned
                            if cross_internal < 0 % Center of circle internal is below the line
                                V_hc = V_hc_rest - V_internal_cap;
                                V_fc = V_fc_cap + V_internal_cap;
                            elseif cross_internal > 0 % Center of circle internal is above the line
                                V_hc = V_hc_rest - V_internal_rest;
                                V_fc = V_fc_cap + V_internal_rest;
                            else % cross_internal == 0, Center of circle internal is on the line
                                V_hc = V_hc_rest - V_hemisphere_internal;
                                V_fc = V_fc_cap + V_hemisphere_internal;
                            end
                        else % cross_fc == 0
                            if cross_internal < 0 % Center of circle internal is below the line
                                V_hc = V_hc_rest - V_internal_cap;
                                V_fc = V_hemisphere_fc + V_internal_cap;
                            else % cross_internal > 0 % Center of circle internal is above the line
                                V_hc = V_hc_rest + V_internal_cap;
                                V_fc = V_hemisphere_fc - V_internal_cap;
                            end
                        end
                    else % cross_hc == 0
                        if cross_fc < 0 % Center of circle hc is below the line
                            if cross_internal < 0 % Center of circle internal is below the line
                                V_hc = V_hemisphere_hc - V_internal_cap;
                                V_fc = V_fc_rest + V_internal_cap;
                            else % cross_internal > 0 % Center of circle internal is above the line
                                V_hc = V_hemisphere_hc + V_internal_cap;
                                V_fc = V_fc_rest - V_internal_cap;
                            end
                        elseif cross_fc > 0 % Center of circle hc is above the line
                            if cross_internal < 0 % Center of circle internal is below the line
                                V_hc = V_hemisphere_hc - V_internal_cap;
                                V_fc = V_fc_cap + V_internal_cap;
                            else % cross_internal > 0 % Center of circle internal is above the line
                                V_hc = V_hemisphere_hc + V_internal_cap;
                                V_fc = V_fc_cap - V_internal_cap;
                            end
                        else % cross_fc == 0
                            if cross_internal < 0 % Center of circle internal is below the line
                               V_hc = V_hemisphere_hc - V_internal_cap;
                               V_fc = V_hemisphere_fc + V_internal_cap;
                            else %if cross_internal > 0 % Center of circle internal is above the line
                               V_hc = V_hemisphere_hc + V_internal_cap;
                               V_fc = V_hemisphere_fc - V_internal_cap;
                            end
                        end
                    end

%                     % or sets rules of VR by ourselves, especially when
%                     % VR<0.5
%                     V_hc = V_hc_cap - V_internal_cap;
%                     V_fc = V_fc_rest + V_internal_cap;
%                     %V_hc = V_hc_cap + V_internal_cap;
%                     %V_fc = V_fc_rest - V_internal_cap;
                    
                    
                    % calculate SAR and VR
                    Surface_area_ratio = surface_hc/surface_fc;
                    %Surface_area_ratio = surface_fc/surface_hc; % i dont know why i need to reverse here to get the correct value...
                    V_ratio = V_hc/V_fc;
                    %V_ratio = V_fc/V_hc;
                    V_total = V_hc + V_fc;
                    
                    % Calculate normalized Contact angle
                    CA_normalized = 180*CA_fc/(CA_hc+CA_fc);                    
                    
                    
                    %now plotting
                    viscircles(loc_fc,radii_fc,'Color',app.color);
                    viscircles(loc_hc,radii_hc,'Color',app.color);
                    viscircles(loc_internal,radii_internal,'Color',app.color);
                    text(int_1(1),int_1(2),['ThetaCA_S_A: ',num2str(CA_snowman)],'Color',app.color)
                    text(int_1(1),int_1(2)+25,['ThetaCA_F_C: ',num2str(CA_fc)],'Color',app.color)
                    text(int_1(1),int_1(2)-25,['ThetaCA_H_C: ',num2str(CA_hc)],'Color',app.color)
                    text(int_1(1)+35,int_1(2)-25,['CA_n_o_r_m_a_l_i_z_e_d: ',num2str(CA_normalized)],'Color',app.color)
                    text(int_1(1)+35,int_1(2)+25,['SurfAreaRatio: ',num2str(Surface_area_ratio)],'Color',app.color)
                    text(int_1(1)+35,int_1(2),['V_h_c/V_f_c: ',num2str(V_ratio)],'Color',app.color)
                    text(int_1(1)+70,int_1(2),['AspectRatio: ',num2str(Aspect_ratio)],'Color',app.color)
                    
                    app.fullData = vertcat(app.fullData,[curveRadii(1),curveRadii(2),curveRadii(3),curveCenters(1,1),curveCenters(1,2),curveCenters(2,1),curveCenters(2,2),curveCenters(3,1),curveCenters(3,2),Aspect_ratio,CA_hc,CA_fc,CA_normalized,CA_snowman,Surface_area_ratio,V_ratio,V_total,V_hc,V_fc,V_hc_cap,V_fc_cap,V_internal_cap,V_hc_rest,V_fc_rest,V_internal_rest,V_sphere_hc,V_sphere_fc,V_sphere_internal,cross_hc,cross_fc,cross_internal]);
                    app.data = [curveRadii(1),curveRadii(2),curveRadii(3),curveCenters(1,1),curveCenters(1,2),curveCenters(2,1),curveCenters(2,2),curveCenters(3,1),curveCenters(3,2),Aspect_ratio,CA_hc,CA_fc,CA_normalized,CA_snowman,Surface_area_ratio,V_ratio,V_total,V_hc,V_fc,V_hc_cap,V_fc_cap,V_internal_cap,V_hc_rest,V_fc_rest,V_sphere_hc,V_sphere_fc,V_internal_rest,V_sphere_internal,cross_hc,cross_fc,cross_internal];
            end

        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            zoom on; title('Zooming, click Measure to Measure')
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            app.gcf = clf('reset');
            app.image = imrotate(app.image,90);
            [m n] = size(app.image);
            axis([0 m 0 n]);
            app.gcf = imshow(app.image);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.color = uisetcolor(app.color,'Select a Color');
            app.Button.BackgroundColor = app.color;
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            app.UITable.Data = {[] [] [] []}
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            switch app.DropletTypeSwitch.Value
                case 'Spherical'
                    table = array2table(app.fullData);
                    table.Properties.VariableNames(1:7) = {'Droplet Radius','Curvature Radius','Distance','Contact Angle HC','Contact Angle FC','Volume Ratio','Janus Ratio'};
                    fName = strcat(app.f(1:end-4),'.xlsx');
                    writetable(table,fName);

                case 'Snowman'
                    meanRow = mean(app.fullData, 'omitnan');  % Calculate the mean for each column, ignoring NaN values if present
                    stdRow = std(app.fullData, 'omitnan');    % Calculate the standard deviation for each column, ignoring NaN values if present
                    emptyRow = nan(1, size(app.fullData, 2)); % Create an empty row (NaN values) of the same length as the number of columns in data                    
                    app.extendedData = [app.fullData; emptyRow; meanRow; stdRow];% Append the empty row, mean row, and standard deviation row to the data                  
                    table = array2table(app.extendedData);% Convert the extended data to a table     
                    numRows = size(table, 1);  % Assuming app.fullData is your data matrix with rows % Example row names construction based on your number of rows
                    rowNames = cell(numRows, 1);  % Preallocate a cell array for row names                    
                    for i = 1:numRows-3 % Populate row names (example: using 'DropNum1', 'DropNum2', ..., 'DropNumN')
                        rowNames{i} = sprintf('DropNum%d', i);
                    end 
                    rowNames = [cellstr(num2str((1:size(table, 1)-3)')); {'EmptyCell'}; {'Mean'}; {'StdDev'};];% Add row names for the mean and standard deviation rows    
                    table.Properties.RowNames = rowNames;% Assign row names to the table
                    
                    % Define VariableNames matching the number of columns in extendedData
                    variableNames = {'HC Drop radius', 'FC Drop radius', 'Internal Drop radius', ...
                        'HC center x', 'HC center y', 'FC center x', 'FC center y', ...
                        'Internal center x', 'Internal center y', 'Aspect Ratio','CA_hc', 'CA_fc', ...
                        'CA_normalized', 'CA_snowman', 'Surface_area_ratio', ...
                        'Volume_ratio_hc/fc', 'V_total', 'V_hc', 'V_fc', 'V_hc_cap', ...
                        'V_fc_cap', 'V_internal_cap', 'V_hc_rest', 'V_fc_rest', ...
                        'V_internal_rest', 'V_sphere_hc', 'V_sphere_fc', ...
                        'V_sphere_internal', 'cross_hc', 'cross_fc', 'cross_internal'};
                    table.Properties.VariableNames = variableNames;% Assign variable names to the table
       
                    fName = strcat(app.f(1:end-4),'.xlsx');
                    writetable(table, fName,"WriteRowNames",true);

            end
        end

        % Button pushed function: CopyResultsButton
        function CopyResultsButtonPushed(app, event)
            clipboard('copy',num2str(app.data));
        end

        % Value changed function: DropletTypeSwitch
        function DropletTypeSwitchValueChanged(app, event)
            value = app.DropletTypeSwitch.Value;
            switch app.DropletTypeSwitch.Value
                case 'Spherical'
                    app.UITable.Visible = 'on';
                    app.CopyResultsButton.Visible = 'on';
                    app.ExportDataButton.Visible = 'on';
                    app.nHCEditField.Visible = 'on';
                    app.nFCEditField.Visible = 'on';
                    app.ncontEditField.Visible = 'on';
                    app.nHCEditFieldLabel.Visible = 'on';
                    app.nFCEditFieldLabel.Visible = 'on';
                    app.ncontEditFieldLabel.Visible = 'on';
                case 'Snowman'
                    app.UITable.Visible = 'on';
                    app.CopyResultsButton.Visible = 'on';
                    app.ExportDataButton.Visible = 'on';
                    app.nHCEditField.Visible = 'on';
                    app.nFCEditField.Visible = 'on';
                    app.ncontEditField.Visible = 'on';
                    app.nHCEditFieldLabel.Visible = 'on';
                    app.nFCEditFieldLabel.Visible = 'on';
                    app.ncontEditFieldLabel.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeasureDropletsUIFigure = uifigure('Visible', 'off');
            app.MeasureDropletsUIFigure.Name = 'Measure Droplets';
            app.OpenImageButton = uibutton(app.MeasureDropletsUIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.nHCEditFieldLabel = uilabel(app.MeasureDropletsUIFigure);
            app.nHCEditFieldLabel.Text = 'n (HC)';
            app.nHCEditField = uieditfield(app.MeasureDropletsUIFigure, 'numeric');
            app.nHCEditField.ValueChangedFcn = createCallbackFcn(app, @nHCEditFieldValueChanged, true);
            app.nHCEditField.Value = 1.33;
            app.nFCEditFieldLabel = uilabel(app.MeasureDropletsUIFigure);
            app.nFCEditFieldLabel.Text = 'n (FC)';
            app.nFCEditField = uieditfield(app.MeasureDropletsUIFigure, 'numeric');
            app.nFCEditField.ValueChangedFcn = createCallbackFcn(app, @nFCEditFieldValueChanged, true);
            app.nFCEditField.Value = 1.33;
            app.ncontEditFieldLabel = uilabel(app.MeasureDropletsUIFigure);
            app.ncontEditFieldLabel.Text = 'n (cont)';
            app.ncontEditField = uieditfield(app.MeasureDropletsUIFigure, 'numeric');
            app.ncontEditField.ValueChangedFcn = createCallbackFcn(app, @ncontEditFieldValueChanged, true);
            app.ncontEditField.Value = 1.33;
            app.MeasureDropletButton = uibutton(app.MeasureDropletsUIFigure, 'push');
            app.MeasureDropletButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureDropletButtonPushed, true);
            app.MeasureDropletButton.Text = 'Measure Droplet';
            app.ZoomButton = uibutton(app.MeasureDropletsUIFigure, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Text = 'Zoom';
            app.RotateButton = uibutton(app.MeasureDropletsUIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rot. ->';
            app.Button = uibutton(app.MeasureDropletsUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.UITable = uitable(app.MeasureDropletsUIFigure);
            app.UITable.ColumnName = {'CA (deg)'; 'CA-err'; 'VR'; 'VR-err'; 'JR'; 'JR-err'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.ExportDataButton = uibutton(app.MeasureDropletsUIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.CopyResultsButton = uibutton(app.MeasureDropletsUIFigure, 'push');
            app.CopyResultsButton.ButtonPushedFcn = createCallbackFcn(app, @CopyResultsButtonPushed, true);
            app.CopyResultsButton.Text = 'Copy Results';
            app.DropletTypeSwitchLabel = uilabel(app.MeasureDropletsUIFigure);
            app.DropletTypeSwitchLabel.Text = 'Droplet Type';
            app.DropletTypeSwitch = uiswitch(app.MeasureDropletsUIFigure, 'slider');
            app.DropletTypeSwitch.Items = {'Spherical', 'Snowman'};
            app.DropletTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @DropletTypeSwitchValueChanged, true);
            app.DropletTypeSwitch.Value = 'Spherical';
            % Show the figure after all components are created
            app.MeasureDropletsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlabCA_Ka_v9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeasureDropletsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeasureDropletsUIFigure)
        end
    end
end